classdef SatelliteOrbitApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        EnterTLEdataPanel           matlab.ui.container.Panel
        EpochtimeEditFieldLabel     matlab.ui.control.Label
        EpochtimeEditField          matlab.ui.control.NumericEditField
        InclinationEditFieldLabel   matlab.ui.control.Label
        InclinationEditField        matlab.ui.control.NumericEditField
        RAANEditFieldLabel          matlab.ui.control.Label
        RAANEditField               matlab.ui.control.NumericEditField
        EccentricityEditFieldLabel  matlab.ui.control.Label
        EccentricityEditField       matlab.ui.control.NumericEditField
        ArgumentofperigeeEditFieldLabel  matlab.ui.control.Label
        ArgumentofperigeeEditField  matlab.ui.control.NumericEditField
        MeananomalyEditFieldLabel   matlab.ui.control.Label
        MeananomalyEditField        matlab.ui.control.NumericEditField
        MeanmotionEditFieldLabel    matlab.ui.control.Label
        MeanmotionEditField         matlab.ui.control.NumericEditField
        TextArea                    matlab.ui.control.TextArea
        UploadDataButton            matlab.ui.control.Button
        PlotOrbitButton             matlab.ui.control.Button
        SatellitesPositionattheepochtimePanel  matlab.ui.container.Panel
        UIAxes2                     matlab.ui.control.UIAxes
        TrajectoryontheOibitalPlanePanel  matlab.ui.container.Panel
        SatelliteOrbitTrackerLabel  matlab.ui.control.Label
        madebyYoonjoongKimLabel     matlab.ui.control.Label
        GroundTrackPanel            matlab.ui.container.Panel
        SettimerangeButtonGroup     matlab.ui.container.ButtonGroup
        CyclesButton                matlab.ui.control.RadioButton
        Spinner                     matlab.ui.control.Spinner
        PlotGroundTrackButton       matlab.ui.control.Button
        SecondsButton               matlab.ui.control.RadioButton
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: UploadDataButton
        function UploadDataButtonPushed(app, event)
            %%% Constants
            global mu D2R Re w_e
            G = 6.67384e-11;      % gravitational constant
            Me = 5.9742e24;       % Mass of Earth
            Re = 6371e+3;
            mu = G*Me;
            D2R = pi/180;
            w_e = 360/(24*60*60); % Angular Velocity of Earth [deg/s]
            
            %%% get Orbital Elements
            global te i RAAN e M n w epoch_time OE Period
            te = app.EpochtimeEditField.Value;
            i = app.InclinationEditField.Value;
            RAAN = app.RAANEditField.Value;            
            e = app.EccentricityEditField.Value;
            M = app.MeananomalyEditField.Value;            
            n = app.MeanmotionEditField.Value;
            w = app.ArgumentofperigeeEditField.Value;
            
            OE = getOE_app(te,i,RAAN,e,w,M,n);
            a = OE(1);
            Period = OE(7);
            epoch_time = OE(8);
            
            %%% display Orbital Elements
            cellArrayText{1} = sprintf('Semimajor Axis  a = \n %.2f [m]\n\n', a);
            cellArrayText{2} = sprintf('Eccentricity  e = \n %.2f \n\n', e);
            cellArrayText{3} = sprintf('Inclination  i = \n %.2f [deg]\n\n', i);
            cellArrayText{4} = sprintf('Right Ascenction of the Ascending Node  RAAN = \n %.2f [deg]\n\n', RAAN);
            cellArrayText{5} = sprintf('Argument of perige  w = \n %.2f [deg]\n\n', w);
            cellArrayText{6} = sprintf('Mean anomaly  M0 = \n %.2f [deg]\n\n', M);
            cellArrayText{7} = sprintf('Period  T = \n %.2f [s]\n', Period);
            app.TextArea.Value = cellArrayText;

        end

        % Value changed function: Spinner
        function SpinnerValueChanged(app, event)
            global trackTime Period
            time = app.Spinner.Value;
            
            if (app.CyclesButton.Value)
                trackTime = time * Period;
            elseif (app.SecondsButton.Value)
                trackTime = time;
            end
            
        end

        % Button pushed function: PlotOrbitButton
        function PlotOrbitButtonPushed(app, event)
            %%% get orbits of satellites  
            global t i epoch_time lat lon OE Period Re
            t = epoch_time;
            [x_curr,y_curr,z_curr] = cal_position_app(t,OE);
            dt = 1;
            for j = 1 : 2*Period
                t = t + dt;
                [OE(j+1,:)] = oblatenessEffects(OE(j,:));
                [r(j,1),nu(j,1)] = cal_Orbit_app(t,OE(j+1,:));
                [x(j,1),y(j,1),z(j,1)] = cal_position_app(t,OE(j+1,:));
                [lon(j,1),lat(j,1)] = groundTrack(t,x(j,1),y(j,1),z(j,1),nu(j,1),OE(j+1,:));
            end
            
            %%% Orbit on the orbital plane
            p_orbit = polaraxes(app.TrajectoryontheOibitalPlanePanel);
            p1 = polarplot(p_orbit,nu,r,'LineWidth',1);
            p1.Color = '#D95319';
            
            %%% Satellite's Current Position
            p_earth = app.UIAxes2;
            plot3(p_earth,x,y,z,'g',...
                x_curr,y_curr,z_curr,'o','MarkerSize',9,'MarkerFaceColor','#EDB120','MarkerEdgeColor','#EDB120');
            hold(p_earth,"on");
            
            % Create the Earth
            load topo topo topomap1;    % load data 
            whos('topo','topomap1');
            [x_s,y_s,z_s] = sphere(50);    % create a sphere 
            s = surface(app.UIAxes2,x_s*Re,y_s*Re,z_s*Re);
            grid on;
            s.FaceColor = 'texturemap';    % use texture mapping
            s.CData = topo;                % set color data to topographic data
            s.EdgeColor = 'none';          % remove edges
            s.FaceLighting = 'gouraud';    % preferred lighting for curved surfaces
            s.SpecularStrength = 0.4;      % change the strength of the reflected light
            axis square off                % set axis to square and remove axis
            view([-30,30])                 % set the viewing angle
        end

        % Button pushed function: PlotGroundTrackButton
        function PlotGroundTrackButtonPushed(app, event)
            global OE trackTime
            %%% Ground Track
            t = 1;
            dt = 1;
            for i = 1 : trackTime + 1
                t = t + dt;
                [OE(i+1,:)] = oblatenessEffects(OE(i,:));
                [r(i,1),nu(i,1)] = cal_Orbit_app(t,OE(i+1,:));
                [x(i,1),y(i,1),z(i,1)] = cal_position_app(t,OE(i+1,:));
                [lon(i,1),lat(i,1)] = groundTrack(t,x(i,1),y(i,1),z(i,1),nu(i,1),OE(i+1,:));
            end
            gx = geoaxes(app.GroundTrackPanel);
            geoscatter(gx,lat,lon-125,'.g');
            geobasemap(gx,'satellite');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.EnterTLEdataPanel = uipanel(app.UIFigure);
            app.EnterTLEdataPanel.Title = 'Enter TLE data';
            app.EpochtimeEditFieldLabel = uilabel(app.EnterTLEdataPanel);
            app.EpochtimeEditFieldLabel.Text = 'Epoch time';
            app.EpochtimeEditField = uieditfield(app.EnterTLEdataPanel, 'numeric');
            app.EpochtimeEditField.Value = 21312.58334491;
            app.InclinationEditFieldLabel = uilabel(app.EnterTLEdataPanel);
            app.InclinationEditFieldLabel.Text = 'Inclination';
            app.InclinationEditField = uieditfield(app.EnterTLEdataPanel, 'numeric');
            app.InclinationEditField.Value = 15.69855487;
            app.RAANEditFieldLabel = uilabel(app.EnterTLEdataPanel);
            app.RAANEditFieldLabel.Text = 'RAAN';
            app.RAANEditField = uieditfield(app.EnterTLEdataPanel, 'numeric');
            app.RAANEditField.Value = 330.8867;
            app.EccentricityEditFieldLabel = uilabel(app.EnterTLEdataPanel);
            app.EccentricityEditFieldLabel.Text = 'Eccentricity';
            app.EccentricityEditField = uieditfield(app.EnterTLEdataPanel, 'numeric');
            app.EccentricityEditField.Value = 3878;
            app.ArgumentofperigeeEditFieldLabel = uilabel(app.EnterTLEdataPanel);
            app.ArgumentofperigeeEditFieldLabel.Text = 'Argument of perigee';
            app.ArgumentofperigeeEditField = uieditfield(app.EnterTLEdataPanel, 'numeric');
            app.ArgumentofperigeeEditField.Value = 293.7652;
            app.MeananomalyEditFieldLabel = uilabel(app.EnterTLEdataPanel);
            app.MeananomalyEditFieldLabel.Text = 'Mean anomaly';
            app.MeananomalyEditField = uieditfield(app.EnterTLEdataPanel, 'numeric');
            app.MeananomalyEditField.Value = 261.668;
            app.MeanmotionEditFieldLabel = uilabel(app.EnterTLEdataPanel);
            app.MeanmotionEditFieldLabel.Text = 'Mean motion';
            app.MeanmotionEditField = uieditfield(app.EnterTLEdataPanel, 'numeric');
            app.MeanmotionEditField.Value = 15.69855487;
            app.TextArea = uitextarea(app.EnterTLEdataPanel);
            app.UploadDataButton = uibutton(app.EnterTLEdataPanel, 'push');
            app.UploadDataButton.ButtonPushedFcn = createCallbackFcn(app, @UploadDataButtonPushed, true);
            app.UploadDataButton.Text = 'Upload Data';
            app.PlotOrbitButton = uibutton(app.EnterTLEdataPanel, 'push');
            app.PlotOrbitButton.ButtonPushedFcn = createCallbackFcn(app, @PlotOrbitButtonPushed, true);
            app.PlotOrbitButton.Text = 'Plot Orbit';
            app.SatellitesPositionattheepochtimePanel = uipanel(app.UIFigure);
            app.SatellitesPositionattheepochtimePanel.Title = 'Satellite''s Position (at the epoch time)';
            app.UIAxes2 = uiaxes(app.SatellitesPositionattheepochtimePanel);
            title(app.UIAxes2, '')
            xlabel(app.UIAxes2, '')
            ylabel(app.UIAxes2, '')
            app.TrajectoryontheOibitalPlanePanel = uipanel(app.UIFigure);
            app.TrajectoryontheOibitalPlanePanel.Title = 'Trajectory on the Oibital Plane';
            app.SatelliteOrbitTrackerLabel = uilabel(app.UIFigure);
            app.SatelliteOrbitTrackerLabel.Text = '< Satellite Orbit Tracker >';
            app.madebyYoonjoongKimLabel = uilabel(app.UIFigure);
            app.madebyYoonjoongKimLabel.Text = 'made by. Yoonjoong Kim';
            app.GroundTrackPanel = uipanel(app.UIFigure);
            app.GroundTrackPanel.Title = 'Ground Track';
            app.SettimerangeButtonGroup = uibuttongroup(app.UIFigure);
            app.SettimerangeButtonGroup.Title = ' Set time range';
            app.CyclesButton = uiradiobutton(app.SettimerangeButtonGroup);
            app.CyclesButton.Text = 'Cycles';
            app.CyclesButton.Value = true;
            app.Spinner = uispinner(app.SettimerangeButtonGroup);
            app.Spinner.ValueChangedFcn = createCallbackFcn(app, @SpinnerValueChanged, true);
            app.PlotGroundTrackButton = uibutton(app.SettimerangeButtonGroup, 'push');
            app.PlotGroundTrackButton.ButtonPushedFcn = createCallbackFcn(app, @PlotGroundTrackButtonPushed, true);
            app.PlotGroundTrackButton.Text = 'Plot Ground Track';
            app.SecondsButton = uiradiobutton(app.SettimerangeButtonGroup);
            app.SecondsButton.Text = 'Seconds';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SatelliteOrbitApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end